classdef Piezo_DAQ_GUIv2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        StartNewDataAcquisitionButton  matlab.ui.control.Button
        PiezoelectricRoadwayDataAcquisitionLabel  matlab.ui.control.Label
        TestParametersPanel            matlab.ui.container.Panel
        TestDurationsEditFieldLabel    matlab.ui.control.Label
        TestDurationsEditField         matlab.ui.control.NumericEditField
        Fs_EditFieldLabel              matlab.ui.control.Label
        Fs_EditField                   matlab.ui.control.NumericEditField
        k_EditFieldLabel               matlab.ui.control.Label
        k_EditField                    matlab.ui.control.NumericEditField
        k_enable_Button                matlab.ui.control.StateButton
        SaveDataPanel                  matlab.ui.container.Panel
        SaveButton                     matlab.ui.control.Button
        FileNameEditFieldLabel         matlab.ui.control.Label
        FileNameEditField              matlab.ui.control.EditField
        InformationPanel               matlab.ui.container.Panel
        LocationEditFieldLabel         matlab.ui.control.Label
        LocationEditField              matlab.ui.control.EditField
        DistancefromcurbawayfromroadcmLabel  matlab.ui.control.Label
        DistancefromcurbawayfromroadcmEditField  matlab.ui.control.EditField
        TrialTypeDropDownLabel         matlab.ui.control.Label
        TrialTypeDropDown              matlab.ui.control.DropDown
        AdditionalNotesTextAreaLabel   matlab.ui.control.Label
        AdditionalNotesTextArea        matlab.ui.control.TextArea
        EnergyAnalysisPanel            matlab.ui.container.Panel
        TotalEnergyEditFieldLabel      matlab.ui.control.Label
        TotalEnergyEditField           matlab.ui.control.NumericEditField
        JLabel                         matlab.ui.control.Label
        ofTotalEnergyEditFieldLabel    matlab.ui.control.Label
        ofTotalEnergyEditField         matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        NumberOfBandsLabel             matlab.ui.control.Label
        NumberOfBandsEditField         matlab.ui.control.NumericEditField
        HzEditField_3Label             matlab.ui.control.Label
        HzEditField_3                  matlab.ui.control.EditField
        ResonancesLabel                matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        MagnitudeSpectrumTab           matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        EnergySpectralDensityTab       matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        BWTable                        matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        ClippingHasOccurredLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
        %====================
        % SAMPLING PARAMETERS
        %====================
        dur; % Test duration (s)
        Fs; % Sampling frequency (Hz)
        k; % Moving average filter (samples)
        
        %====================
        % LOCATION PARAMETERS
        %====================
        location; % location of test
        curb_distance; % distance from curb away from road
        trial_type; % Single car / Light traffic / Heavy traffic / Other
        notes; % Extra notes about test
        date_time; % date and time of the test
        
        %=================
        % DATA ACQUISITION
        %=================
        t; % time vector
        z; % z(t) acceleration in z-direction
        filtered_z; % filtered with moving average filter
        f; % frequency vector
        Z; % Z(f) = FFT{z(t)} Spectrum of z(t)
        filtered_Z; % Filtered with moving average filter
        N; % number of samples in the signal
        
        %====================
        % ENERGY CALCULATIONS
        %====================
        E_fraction; % User defined
        E_total; % Calculated total energy in signal 
        E_d; % Energy Spectral density |Z(f)|^2/N
        bw_table; % [lower bound, upper bound, band resonance, % of total energy]
        
        %========================
        % MISCILLANEOUS VARIABLES
        %========================
        file_name; % User defined
        first_run; % 1 when data's collected for 1st time, 0 otherwise
        loading_bar; % displays progress of calculations
    end
    
    methods (Access = private)
        
        function plot_data(app)
            app.N = length(app.Z); % Number of samples
            if app.k_enable_Button.Text == 'ON '             
                plot(app.UIAxes,app.t,app.filtered_z); % filtered time plot
                plot(app.UIAxes2,app.f,app.filtered_Z); % filtered frequency plot
            else % if k enable button is off
                plot(app.UIAxes,app.t,app.z); % unfiltered time plot
                plot(app.UIAxes2,app.f,app.Z); % unfiltered frequency plot
            end
            app.UIAxes2.XLim = [0 200]; % frequency range 0 - 200 Hz
            app.UIAxes2.YLim = [0 inf];
            energy_calculations(app);
        end
        
        function energy_calculations(app)
            %=========================================================================
            % Energy Calculations
            %=========================================================================
            
            % INPUTS
            % User Input  : E_fraction | fraction of total Energy E_fraction
            % Other Inputs: f, Z, filtered_Z | frequency (Hz), Z(f), filtered Z(f)
            
            % OUTPUTS
            % Graphed outputs: Energy Spectral Density (shaded) vs. frequency (Hz)
            % Tabular outputs: [Lower Bound | Upper Bound | Band Resonance | Band Energy]
            
            %=========================================================================
            %load data_table.mat t z filtered_z f Z filtered_Z
            %load energy_values.mat E_fraction
            app.E_fraction = app.E_fraction / 100; % Changes from % to decimal
            
            % Energy Spectral Density Calculation (single-sided)
            %app.N = length(app.Z);
            if app.k_enable_Button.Text == 'ON '
                app.E_d = app.filtered_Z.^2./app.N; % Filtered Energy Spectral Density
            else
                app.E_d = app.Z.^2./app.N; % Unfiltered Energy Spectral Density
            end
            % Zero out the negative side of the energy spectrum
            n = 1;
            for n = 1:app.N
                if app.f(n) < 0
                    app.E_d(n) = 0; 
                else
                    break
                end
            end
            % Double the Energy everywhere except the two signal endpoints
            app.E_d = app.E_d * 2;
            app.E_d(n) = app.E_d(n)/2; % point at which f = 0
            
            % Total and Partial Energy calculation
            app.E_total = sum(app.E_d); % Total Energy of Signal
            app.TotalEnergyEditField.Value = app.E_total; % displays total energy in text box
            E_partial = app.E_total * app.E_fraction;
            
            % Generates a 2 column matrix with the indices and values of the maximums [index value]
            E_d_temp = app.E_d;
            E_sum = 0;
            index_value = [0 0];
            for n = 1:app.N
                [value, index] = max(E_d_temp); % finds max value and index
                E_d_temp(index) = 0; % zeros out the max value
                E_sum = E_sum + value;
                index_value(n,1:2) = [index value]; % add a row to the 2-column matrix
                if E_sum >= E_partial % if the sum exceeds the specified threshold
                    break
                end
            end
            clear E_d_temp E_sum
            
            index_value = sortrows(index_value); % puts values in ascending order by index
            index = index_value(:,1); % sorted indexes
            clear index_value value
            
            % Starting column Matrices for data table
            lower_bound = app.f(index(1)); % column matrix of lower bounds
            upper_bound = 0; % column matrix of upper bounds
            band_resonance = 0; %  "    "    "  band resonances
            band_energy = 0; %     "    "    "  percentage of total energy in the band
            
            % Temp variables to track indexes
            lower_index = 1;
            upper_index = 1;
            res_index = 1;
            
            row = 1; 
            if size(index) == 1
                lower_bound = app.f(index);
                upper_bound = app.f(index);
                band_resonance = app.f(index);
                band_energy = app.E_d(index) / app.E_total;
            else
                for n = 1:size(index)-1
                    if index(n+1) - index(n) ~= 1 || n == length(index)-1 % if indexes are NOT consecutive
                        % Upper Bound Calculation
                        upper_index = index(n);
                        upper_bound(row,1) = app.f(upper_index);
                        
                        % Band Resonance Calculation
                        [~, res_index] = max(app.E_d(lower_index:upper_index));
                        res_index = res_index + lower_index - 1;
                        band_resonance(row,1) = app.f(res_index);
                        
                        % Band Energy Calculation
                        band_sum = sum(app.E_d(lower_index:upper_index));
                        band_energy(row,1) = band_sum / app.E_total;
                        
                        if n < size(index,1)-1
                            row = row + 1;
                            lower_index = index(n+1);
                            lower_bound(row,1) = app.f(index(n+1));
                        end
                    end
                end
            end
            clear lower_index upper_index res_index
            
            app.bw_table = [lower_bound, upper_bound, band_resonance, band_energy];
            app.UITable.Data = app.bw_table; % Displays the table
            
            % Plot a shaded graph
            clear H1 H
            H1 = area(app.UIAxes3,app.f,app.E_d,'FaceColor','w');
            
            hold(app.UIAxes3,'on')
            H = zeros(size(app.bw_table,1),1); % creates a column matrix of Areas
            for n = 1:size(app.bw_table,1)
                bounds = (app.f >= lower_bound(n)) & (app.f <= upper_bound(n));
                H(n) = area(app.UIAxes3,app.f(bounds),app.E_d(bounds));
                set(H(n),'FaceColor','y');
            end
            
            app.UIAxes3.XLim = [0 200];
            app.UIAxes3.YLim = [0 inf];
            hold(app.UIAxes3,'off')
            clear bounds
            print_resonances(app);
            app.E_fraction = app.E_fraction * 100; % decimal to perfent
        end
        
        function print_resonances(app)
            band_count = length(app.bw_table(:,1));
            app.NumberOfBandsEditField.Value = band_count;
            band_string = '';
            
            for n = 1:band_count % create a string
                band_string = [band_string num2str(app.bw_table(n,3))];
                if n < band_count
                    band_string = [band_string ', '];
                end
            end
            app.HzEditField_3.Value = band_string;
            clear band_string band_count
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %=========================================================================
            % SAMPLING PARAMETERS
            %=========================================================================
            app.dur = 10; % default duration set to 10s
            app.Fs = 1e3; % default sampling frequency 1 kHz
            app.k = 50;   % default moving average filter coefficient
            
            %=========================================================================
            % LOCATION PARAMETERS
            %=========================================================================
            app.location = 'location_1'; % default
            app.curb_distance = 0; % default
            app.trial_type = 'Single Car'; % Single car / Light traffic / Heavy traffic / Other
            app.notes = ''; % default
            app.date_time = datetime(now,'ConvertFrom','datenum'); % gets date & time of data collection
            
            %=========================================================================
            % DATA ACQUISITION
            %=========================================================================
            app.t = 0; % time vector
            app.z = 0; % z(t) acceleration in z-direction
            app.filtered_z = 0; % filtered with moving average filter
            app.f = 0; % frequency vector
            app.Z = 0; % Z(f) = FFT{z(t)} Spectrum of z(t)
            app.filtered_Z = 0; % Filtered with moving average filter
            app.N = 0; % number of samples in the signal
            
            %=========================================================================
            % ENERGY CALCULATIONS
            %=========================================================================
            app.E_fraction = 90; % Default 90% of energy in the signal
            app.E_total = 0; % default
            app.E_d = 0; % default
            app.bw_table = zeros(1,4);
            
            %=========================================================================
            % MISCILLANEOUS VARIABLES
            %=========================================================================
            app.file_name = 'roadway_data_1'; % default
            app.first_run = 0; % 0 if Data hasn't been taken yet. 1 if Data has been taken
            app.loading_bar = 0;
            
            %=========================================================================
            % GUI OBJECT DEFAULT STATES
            %=========================================================================
            app.SaveButton.Enable = 'off'; % disables save function until data is collected
            app.k_EditField.Enable = 'off'; % disables moving average filter as default
            app.k_enable_Button.Text = 'OFF'; % dispalys 'OFF' as default
            app.ClippingHasOccurredLabel.Visible = 'off';
        end

        % Value changed function: TestDurationsEditField
        function TestDurationsEditFieldValueChanged(app, event)
            app.dur = app.TestDurationsEditField.Value;
        end

        % Button pushed function: StartNewDataAcquisitionButton
        function StartNewDataAcquisitionButtonPushed(app, event)
            app.ClippingHasOccurredLabel.Visible = 'off';
            app.loading_bar = waitbar(0,'Initializing...');          
            %=========================================================================
            % COMMUNICATION WITH THE ANALOG DISCOVERY
            %=========================================================================
            %
            daq.getDevices                                  %Detects Analog Discovery
            s = daq.createSession('digilent');              %Creates session for Analog Discovery
            addAnalogInputChannel(s, 'AD1', 1, 'Voltage');  %Sets up analog input channel to %channel 1 with measurement type of voltage difference 
            addAnalogInputChannel(s, 'AD1', 2 , 'Voltage');
            
            % Load parameters
            s.Rate = app.Fs;                                     %Session Sample rate in Hz
            %s.Channels.Range = [-25 25];                    %Sets voltage range from -25 to 25 V
            s.DurationInSeconds = app.dur;
            waitbar(.125,app.loading_bar,'Taking Data...')
            [data, ~, ~] = startForeground(s);%creates a column matrix  to the sampling rate size
            data = data - mean(data); % Getting rid of the vitural ground & recentering the signal about 0
            waitbar(.25,app.loading_bar,'Clipping Detection...');
            %
            %=========================================================================
            
            Vcc = 5; % 0 - 5 V rails on amplifiers
           
            % Time specifications
            dt = 1/app.Fs; % seconds / sample
            app.t = (0:dt:app.dur-dt).'; % creates a column matrix of discrete time values
            app.N = length(app.t); % size of the sampled signal
            clear dt
            
            %=========================================================================
            % TEST (WITHOUT THE ANALOG DISCOVERY)
            %=========================================================================
            %{
            % Data framing
            data = zeros(app.N,2);           
            %{
            w = 2*pi*5; % angular frquency of test signal
            signal = 0;
            for n = 1:2:51
                signal = signal + 5*sin(n*w*app.t)/n;
            end
            signal = signal + .1; % introduce a DC offset
            data(:,1) = 0.1 * signal;
            data(:,2) = signal;
            %}
            % Getting rid of the vitural ground & recentering the signal about 0
            csv_path = 'C:\Users\David\Documents\MATLAB\Piezoelectric\Initial Testing\Test1_slowmph.csv';
            data(:,2) = readmatrix(csv_path,'Range','B2:B8001');
            data = data - mean(data);
            
            % introducing clipping distortion
            for n = 1:app.N
                % 100x signal clipping
                if data(n,2) > Vcc/2
                    data(n,2) = Vcc/2;
                elseif data(n,2) < -Vcc/2
                    data(n,2) = -Vcc/2;
                end
                % 10x signal clipping
                if data(n,1) > Vcc/2
                    data(n,1) = Vcc/2;
                    app.ClippingHasOccurredLabel.Visible = 'on';
                elseif data(n,1) < -Vcc/2
                    data(n,1) = -Vcc/2;
                    app.ClippingHasOccurredLabel.Visible = 'on';
                end
            end
            waitbar(.25,app.loading_bar,'Taking Data...');
            %}
            %=========================================================================
            
            % Clipping detection
            app.z = zeros(app.N,1); % z(t) is will be the acceleration signal in the z-direction 
            for n = 1:app.N
                if (data(n,2) > -Vcc/2) && (data(n,2) < Vcc/2)
                    app.z(n) = 0.01 * data(n,2);
                else
                    app.z(n) = 0.1 * data(n,1);
                end
            end
            clear data
            
            % Frequency specifications
            dF = app.Fs/app.N; % Hz
            app.f = (-app.Fs/2:dF:app.Fs/2-dF).'; % Hz
            clear dF
            
            % Moving average filter specifications
            app.filtered_z = movmean(app.z,app.k); %Low pass filter on taken data
            
            waitbar(.375,app.loading_bar,'Calculating FFT...');
            
            % Fourier Transform of data
            app.Z = abs(fftshift(fft(app.z))); % |Z(f)| = |FFT{z(t)}|
            app.filtered_Z = abs(fftshift(fft(app.filtered_z))); % Filtered Fourier Transform
                        
            plot_data(app); % updates all plots
            app.date_time = datetime(now,'ConvertFrom','datenum'); % gets date & time of data collection
            app.SaveButton.Enable = 'on'; % enables save button
            app.first_run = 1;
            waitbar(.625,app.loading_bar,'Calculating Energy...');
            energy_calculations(app); % Perform all energy calculations
            waitbar(1,app.loading_bar,'Finishing');
            close(app.loading_bar);
        end

        % Value changed function: Fs_EditField
        function Fs_EditFieldValueChanged(app, event)
            app.Fs = app.Fs_EditField.Value;
            app.TestDurationsEditField.Limits = [1 1048575/app.Fs];
        end

        % Value changed function: k_EditField
        function k_EditFieldValueChanged(app, event)
            app.k = app.k_EditField.Value; % updates the k value
            
            if app.first_run == 1 % if data has been taken at least once
                app.filtered_z = movmean(app.z,app.k); % calculates a new filtered_z based on the new k
                app.filtered_Z = abs(fftshift(fft(app.filtered_z))); % new Filtered Fourier Transform
                plot_data(app);
                if app.k_enable_Button.Text == 'ON '
                    energy_calculations(app);
                end
            end
        end

        % Callback function
        function FileNameTextAreaValueChanged(app, event)
            app.file_name = app.FileNameTextArea.Value;   
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Creates a *.txt file
            %selpath = uigetdir();
            %file_path = [selpath '\' app.file_name];
            txt_file_name = [app.file_name '.txt']; % concatenates .txt to file_name
            file_ID = fopen(txt_file_name,'w'); % creates a new *.txt file
            fprintf(file_ID,'==============================================\n');            
            fprintf(file_ID,'PIEZOELECTRIC ROADWAY DATA ACQUISITION REV 2.0\n');
            fprintf(file_ID,'==============================================\n\n');
            fprintf(file_ID,['Test Performed: ' datestr(app.date_time) '\n\n']);
            fprintf(file_ID,'Files Generated\n');
            fprintf(file_ID,'---------------\n');
            fprintf(file_ID,[app.file_name '.txt\n']);
            fprintf(file_ID,[app.file_name '.csv\n']);
            fprintf(file_ID,[app.file_name '.dat\n\n']);
            fprintf(file_ID,'Sampling Parameters\n');
            fprintf(file_ID,'-------------------\n');
            fprintf(file_ID,['Test Duration: ' num2str(app.dur) ' s\n']);
            fprintf(file_ID,['Sampling frequency: ' num2str(app.Fs) ' Hz\n']);
            if app.k_enable_Button.Text == 'ON '
                fprintf(file_ID,['Moving Average Filter: ' num2str(app.k) ' samples\n\n']);
            else
                fprintf(file_ID,'Moving Average Filter: OFF\n\n');
            end
            fprintf(file_ID,'Location Parameters\n');
            fprintf(file_ID,'-------------------\n');
            fprintf(file_ID,['Location: ' app.location '\n']);
            fprintf(file_ID,['Distance from curb away from road: ' num2str(app.curb_distance) ' cm\n']);
            fprintf(file_ID,['Trial Type: ' app.trial_type '\n\n']);
            fprintf(file_ID,'Energy Calculations\n');
            fprintf(file_ID,'-------------------\n');
            fprintf(file_ID,['Total Energy: ' num2str(app.TotalEnergyEditField.Value) ' J\n']);
            fprintf(file_ID,['Energy Threshold: ' num2str(app.ofTotalEnergyEditField.Value) '%%\n']);
            fprintf(file_ID,['Number of Bands: ' num2str(length(app.bw_table(:,1))) '\n\n']);
            fprintf(file_ID,'Additional Notes\n');
            fprintf(file_ID,'----------------\n');
            fprintf(file_ID,app.notes);
            fclose(file_ID); % closes *.txt file
            
            % Creates a *.csv file
            csv_file_name = [app.file_name '.csv']; % concatenates .csv to file_name
            
            % column titles
            t1 = "Time (s)";
            t2 = "z(t)";
            t3 = strcat("z(t)  k = ",num2str(app.k));
            t4 = "Frequency (Hz)";
            t5 = "|Z(f)|";
            t6 = strcat("|Z(f)|  k = ",num2str(app.k));
            t7 = "|Z(f)|^2/N";
            column_titles = [t1 t2 t3 t4 t5 t6 t7];
            clear t1 t2 t3 t4 t5 t6 t7
            
            % save all the data into a table
            raw_data = [app.t app.z app.filtered_z app.f app.Z app.filtered_Z app.E_d];
            titled_table = array2table(raw_data,'VariableNames',column_titles);
            writetable(titled_table, csv_file_name); 
            clear raw_data titled_table column_titles
            
            % creates a *.mat file
            dat_file_name = [app.file_name '.dat']; % concatenates .mat to file_name
            t1 = "Lower Bound";
            t2 = "Upper Bound";
            t3 = "Band Resonance";
            t4 = "% of Total Energy";
            column_titles = [t1 t2 t3 t4];
            titled_bw_table = array2table(app.bw_table,'VariableNames',column_titles);
            writetable(titled_bw_table, dat_file_name);
            %save mat_file_name titled_bw_table
            clear t1 t2 t3 t4 column_titles titled_bw_table
        end

        % Value changed function: LocationEditField
        function LocationEditFieldValueChanged(app, event)
            app.location = app.LocationEditField.Value;
        end

        % Value changed function: 
        % DistancefromcurbawayfromroadcmEditField
        function DistancefromcurbawayfromroadcmEditFieldValueChanged(app, event)
            app.curb_distance = app.DistancefromcurbawayfromroadcmEditField.Value;
        end

        % Value changed function: TrialTypeDropDown
        function TrialTypeDropDownValueChanged(app, event)
            app.trial_type = app.TrialTypeDropDown.Value;
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            app.file_name = app.FileNameEditField.Value;
        end

        % Value changed function: k_enable_Button
        function k_enable_ButtonValueChanged(app, event)
            if app.k_enable_Button.Text == 'OFF' % if enable was previously in off state
                app.k_enable_Button.Text = 'ON '; % Change text on button to read 'ON'
                app.k_EditField.Enable = 'on'; % enables user to enter k 

            else % if enable was previously in on state
                app.k_enable_Button.Text = 'OFF';
                app.k_EditField.Enable = 'off'; % disables user from entering k values
            end
            
            if app.first_run == 1
                plot_data(app);
            end
        end

        % Value changed function: ofTotalEnergyEditField
        function ofTotalEnergyEditFieldValueChanged(app, event)
            old_val = app.E_fraction;
            app.E_fraction = app.ofTotalEnergyEditField.Value;
            if  app.E_fraction ~= old_val && app.first_run == 1
                energy_calculations(app);
                print_resonances(app);
            end
        end

        % Value changed function: AdditionalNotesTextArea
        function AdditionalNotesTextAreaValueChanged(app, event)
            app.notes = char(app.AdditionalNotesTextArea.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Roadway signal')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'z(t) Acceleration (g)')
            app.StartNewDataAcquisitionButton = uibutton(app.UIFigure, 'push');
            app.StartNewDataAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @StartNewDataAcquisitionButtonPushed, true);
            app.StartNewDataAcquisitionButton.Text = 'Start New Data Acquisition';
            app.PiezoelectricRoadwayDataAcquisitionLabel = uilabel(app.UIFigure);
            app.PiezoelectricRoadwayDataAcquisitionLabel.Text = 'Piezoelectric Roadway Data Acquisition';
            app.TestParametersPanel = uipanel(app.UIFigure);
            app.TestParametersPanel.Title = 'Test Parameters';
            app.TestDurationsEditFieldLabel = uilabel(app.TestParametersPanel);
            app.TestDurationsEditFieldLabel.Text = 'Test Duration (s)';
            app.TestDurationsEditField = uieditfield(app.TestParametersPanel, 'numeric');
            app.TestDurationsEditField.Limits = [1 86400];
            app.TestDurationsEditField.ValueChangedFcn = createCallbackFcn(app, @TestDurationsEditFieldValueChanged, true);
            app.TestDurationsEditField.Value = 10;
            app.Fs_EditFieldLabel = uilabel(app.TestParametersPanel);
            app.Fs_EditFieldLabel.Text = 'Sampling Frequency (Hz)';
            app.Fs_EditField = uieditfield(app.TestParametersPanel, 'numeric');
            app.Fs_EditField.Limits = [1 1000000];
            app.Fs_EditField.ValueChangedFcn = createCallbackFcn(app, @Fs_EditFieldValueChanged, true);
            app.Fs_EditField.Value = 1000;
            app.k_EditFieldLabel = uilabel(app.TestParametersPanel);
            app.k_EditFieldLabel.Text = 'Moving Average Filter (Samples)';
            app.k_EditField = uieditfield(app.TestParametersPanel, 'numeric');
            app.k_EditField.UpperLimitInclusive = 'off';
            app.k_EditField.Limits = [2 Inf];
            app.k_EditField.RoundFractionalValues = 'on';
            app.k_EditField.ValueChangedFcn = createCallbackFcn(app, @k_EditFieldValueChanged, true);
            app.k_EditField.Value = 50;
            app.k_enable_Button = uibutton(app.TestParametersPanel, 'state');
            app.k_enable_Button.ValueChangedFcn = createCallbackFcn(app, @k_enable_ButtonValueChanged, true);
            app.k_enable_Button.Text = 'OFF';
            app.SaveDataPanel = uipanel(app.UIFigure);
            app.SaveDataPanel.Title = 'Save Data';
            app.SaveButton = uibutton(app.SaveDataPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.FileNameEditFieldLabel = uilabel(app.SaveDataPanel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.SaveDataPanel, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.FileNameEditField.Value = 'roadway_data_1';
            app.InformationPanel = uipanel(app.UIFigure);
            app.InformationPanel.Title = 'Information';
            app.LocationEditFieldLabel = uilabel(app.InformationPanel);
            app.LocationEditFieldLabel.Text = 'Location';
            app.LocationEditField = uieditfield(app.InformationPanel, 'text');
            app.LocationEditField.ValueChangedFcn = createCallbackFcn(app, @LocationEditFieldValueChanged, true);
            app.DistancefromcurbawayfromroadcmLabel = uilabel(app.InformationPanel);
            app.DistancefromcurbawayfromroadcmLabel.Text = {'Distance from curb '; 'away from road (cm)'};
            app.DistancefromcurbawayfromroadcmEditField = uieditfield(app.InformationPanel, 'text');
            app.DistancefromcurbawayfromroadcmEditField.ValueChangedFcn = createCallbackFcn(app, @DistancefromcurbawayfromroadcmEditFieldValueChanged, true);
            app.DistancefromcurbawayfromroadcmEditField.Value = '0';
            app.TrialTypeDropDownLabel = uilabel(app.InformationPanel);
            app.TrialTypeDropDownLabel.Text = 'Trial Type';
            app.TrialTypeDropDown = uidropdown(app.InformationPanel);
            app.TrialTypeDropDown.Items = {'Single Car', 'Light Traffic', 'Heavy Traffic', 'Other'};
            app.TrialTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TrialTypeDropDownValueChanged, true);
            app.TrialTypeDropDown.Value = 'Single Car';
            app.AdditionalNotesTextAreaLabel = uilabel(app.InformationPanel);
            app.AdditionalNotesTextAreaLabel.Text = 'Additional Notes';
            app.AdditionalNotesTextArea = uitextarea(app.InformationPanel);
            app.AdditionalNotesTextArea.ValueChangedFcn = createCallbackFcn(app, @AdditionalNotesTextAreaValueChanged, true);
            app.EnergyAnalysisPanel = uipanel(app.UIFigure);
            app.EnergyAnalysisPanel.Title = 'Energy Analysis';
            app.TotalEnergyEditFieldLabel = uilabel(app.EnergyAnalysisPanel);
            app.TotalEnergyEditFieldLabel.Text = 'Total Energy';
            app.TotalEnergyEditField = uieditfield(app.EnergyAnalysisPanel, 'numeric');
            app.TotalEnergyEditField.Editable = 'off';
            app.JLabel = uilabel(app.EnergyAnalysisPanel);
            app.JLabel.Text = 'J';
            app.ofTotalEnergyEditFieldLabel = uilabel(app.EnergyAnalysisPanel);
            app.ofTotalEnergyEditFieldLabel.Text = '% of Total Energy';
            app.ofTotalEnergyEditField = uieditfield(app.EnergyAnalysisPanel, 'numeric');
            app.ofTotalEnergyEditField.Limits = [0 100];
            app.ofTotalEnergyEditField.ValueChangedFcn = createCallbackFcn(app, @ofTotalEnergyEditFieldValueChanged, true);
            app.ofTotalEnergyEditField.Value = 90;
            app.Label = uilabel(app.EnergyAnalysisPanel);
            app.Label.Text = '%';
            app.NumberOfBandsLabel = uilabel(app.EnergyAnalysisPanel);
            app.NumberOfBandsLabel.Text = 'Number of Bands';
            app.NumberOfBandsEditField = uieditfield(app.EnergyAnalysisPanel, 'numeric');
            app.NumberOfBandsEditField.Limits = [0 Inf];
            app.NumberOfBandsEditField.Editable = 'off';
            app.NumberOfBandsEditField.Value = 1;
            app.HzEditField_3Label = uilabel(app.EnergyAnalysisPanel);
            app.HzEditField_3Label.Text = 'Hz';
            app.HzEditField_3 = uieditfield(app.EnergyAnalysisPanel, 'text');
            app.HzEditField_3.Editable = 'off';
            app.ResonancesLabel = uilabel(app.EnergyAnalysisPanel);
            app.ResonancesLabel.Text = 'Resonances';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MagnitudeSpectrumTab = uitab(app.TabGroup);
            app.MagnitudeSpectrumTab.Title = 'Magnitude Spectrum';
            app.UIAxes2 = uiaxes(app.MagnitudeSpectrumTab);
            title(app.UIAxes2, 'Frequency Response')
            xlabel(app.UIAxes2, 'Frequency (Hz)')
            ylabel(app.UIAxes2, '|Z(f)|')
            app.UIAxes2.XLim = [0 200];
            app.EnergySpectralDensityTab = uitab(app.TabGroup);
            app.EnergySpectralDensityTab.Title = 'Energy Spectral Density';
            app.UIAxes3 = uiaxes(app.EnergySpectralDensityTab);
            title(app.UIAxes3, 'Energy Spectral Density')
            xlabel(app.UIAxes3, 'Frequency (Hz)')
            ylabel(app.UIAxes3, '|Z(f)|^2/N')
            app.UIAxes3.XLim = [0 200];
            app.BWTable = uitab(app.TabGroup);
            app.BWTable.Title = 'BW Table';
            app.UITable = uitable(app.BWTable);
            app.UITable.ColumnName = {'Lower Bound'; 'Upper Bound'; 'Band Resonance'; '% of Total Energy'};
            app.UITable.RowName = {};
            app.ClippingHasOccurredLabel = uilabel(app.UIFigure);
            app.ClippingHasOccurredLabel.Text = 'Clipping Has Occurred';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Piezo_DAQ_GUIv2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end